classdef DFTensiometer_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DFTensiometerUIFigure        matlab.ui.Figure
        RecordingStatusLabel         matlab.ui.control.Label
        DFTensiometerLabel           matlab.ui.control.Label
        RecordCheck                  matlab.ui.control.EditField
        RecordSoundButton            matlab.ui.control.Button
        spokeGaugeDropDown           matlab.ui.control.DropDown
        SpokeGaugeDropDownLabel      matlab.ui.control.Label
        UploadSoundButton            matlab.ui.control.Button
        TensionkgFEditField          matlab.ui.control.NumericEditField
        TensionkgFEditFieldLabel     matlab.ui.control.Label
        SpokeLengthmmEditField       matlab.ui.control.NumericEditField
        SpokeLengthmmEditFieldLabel  matlab.ui.control.Label
        CalculateButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        sound_data % Description
        sound_data_fs %
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            gauge = str2double(app.spokeGaugeDropDown.Value); %something in here isn't working :(
            length = app.SpokeLengthmmEditField.Value;
            [upper_bound,lower_bound] = bandpass_frequencies(gauge,length);
            frequency = natural_frequency(app.sound_data, app.sound_data_fs, upper_bound, lower_bound); 
        
            % Substitute values into tension function
            tension_value = tension(length, frequency, gauge);
        
            % Display the calculated tension in the TensionEditField
            app.TensionkgFEditField.Value = tension_value;
        end

        % Button pushed function: UploadSoundButton
        function UploadSoundButtonPushed(app, event)
            [file,path] = uigetfile("*.*");
            addpath(path)
            [y, Fs] = audioread(file);
            app.sound_data = y;
            app.sound_data_fs = Fs;
        end

        % Button pushed function: RecordSoundButton
        function RecordSoundButtonPushed(app, event)
            fs = 44100; 
            pluck = audiorecorder(fs,16,1); 
            app.RecordCheck.Value = 'Recording...';
                app.RecordCheck.FontColor = [1 1 1];
                app.RecordCheck.BackgroundColor = [1 0 0];
            recordblocking(pluck,5);
            app.RecordCheck.Value = 'Done!';
                app.RecordCheck.FontColor = [0 0 0];
                app.RecordCheck.BackgroundColor = [0 1 0];
            app.sound_data = getaudiodata(pluck);
            app.sound_data_fs = fs;
                pause(10)
                app.RecordCheck.Value = '';
                app.RecordCheck.FontColor = [0 0 0];
                app.RecordCheck.BackgroundColor = [1 1 1];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DFTensiometerUIFigure = uifigure('Visible', 'off');
            app.DFTensiometerUIFigure.Name = 'DFTensiometer';
            app.CalculateButton = uibutton(app.DFTensiometerUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SpokeLengthmmEditFieldLabel = uilabel(app.DFTensiometerUIFigure);
            app.SpokeLengthmmEditFieldLabel.Text = 'Spoke Length (mm)';
            app.SpokeLengthmmEditField = uieditfield(app.DFTensiometerUIFigure, 'numeric');
            app.SpokeLengthmmEditField.LowerLimitInclusive = 'off';
            app.SpokeLengthmmEditField.Limits = [100 Inf];
            app.SpokeLengthmmEditField.Value = 248;
            app.TensionkgFEditFieldLabel = uilabel(app.DFTensiometerUIFigure);
            app.TensionkgFEditFieldLabel.Text = 'Tension (kg-F)';
            app.TensionkgFEditField = uieditfield(app.DFTensiometerUIFigure, 'numeric');
            app.TensionkgFEditField.ValueDisplayFormat = '%.0f';
            app.TensionkgFEditField.Editable = 'off';
            app.UploadSoundButton = uibutton(app.DFTensiometerUIFigure, 'push');
            app.UploadSoundButton.ButtonPushedFcn = createCallbackFcn(app, @UploadSoundButtonPushed, true);
            app.UploadSoundButton.Text = 'Upload Sound';
            app.SpokeGaugeDropDownLabel = uilabel(app.DFTensiometerUIFigure);
            app.SpokeGaugeDropDownLabel.Text = 'Spoke Gauge';
            app.spokeGaugeDropDown = uidropdown(app.DFTensiometerUIFigure);
            app.spokeGaugeDropDown.Items = {'14', '15', '16', '17', '18'};
            app.spokeGaugeDropDown.Value = '14';
            app.RecordSoundButton = uibutton(app.DFTensiometerUIFigure, 'push');
            app.RecordSoundButton.ButtonPushedFcn = createCallbackFcn(app, @RecordSoundButtonPushed, true);
            app.RecordSoundButton.Text = 'Record Sound';
            app.RecordCheck = uieditfield(app.DFTensiometerUIFigure, 'text');
            app.RecordCheck.Editable = 'off';
            app.DFTensiometerLabel = uilabel(app.DFTensiometerUIFigure);
            app.DFTensiometerLabel.Text = 'DFTensiometer';
            app.RecordingStatusLabel = uilabel(app.DFTensiometerUIFigure);
            app.RecordingStatusLabel.Text = 'Recording Status:';
            % Show the figure after all components are created
            app.DFTensiometerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DFTensiometer_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DFTensiometerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DFTensiometerUIFigure)
        end
    end
end